classdef gpio_mqtt_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MQTTGPIOGUIUIFigure  matlab.ui.Figure
        SendLEDsButton       matlab.ui.control.Button
        GreenSwitch          matlab.ui.control.ToggleSwitch
        GreenSwitchLabel     matlab.ui.control.Label
        YellowSwitch         matlab.ui.control.ToggleSwitch
        YellowSwitchLabel    matlab.ui.control.Label
        RedSwitch            matlab.ui.control.ToggleSwitch
        RedSwitchLabel       matlab.ui.control.Label
        CounterLabel         matlab.ui.control.Label
        GreenOffButton       matlab.ui.control.Button
        YellowOffButton      matlab.ui.control.Button
        RedOffButton         matlab.ui.control.Button
        GreenOnButton        matlab.ui.control.Button
        YellowOnButton       matlab.ui.control.Button
        RedOnButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        mqClient;
        counter = 0;
    end
    
    methods (Access = private)

        function sendMessage(app, messageType, payload)
            messageObj.type = messageType;
            messageObj.payload = payload;
            message = jsonencode(messageObj);
            fprintf("Sending: %s\n", message);
            write(app.mqClient, "fisherds/to_pi", message);
        end

                
        function myCallback(app, topic, message)
            fprintf("Received: %s\n", message);
            messageCell = jsondecode(message);
            messageType = messageCell.type;

            if messageType == "button"
%                 payload = messageCell.payload; % Dangerous since payload might not exist!
                app.counter = app.counter + 1;
                app.CounterLabel.Text = sprintf("Counter = %d", app.counter);
            end

            if messageType == "reset"
                app.counter = 0;
                app.CounterLabel.Text = sprintf("Counter = %d", app.counter);
            end
            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://broker.hivemq.com");
            app.mqClient.subscribe("fisherds/to_comp", "Callback", @app.myCallback);
        end

        % Button pushed function: RedOnButton
        function RedOnButtonPushed(app, event)
            app.sendMessage("red", "on");
        end

        % Button pushed function: RedOffButton
        function RedOffButtonPushed(app, event)
            app.sendMessage("red", "off");
        end

        % Button pushed function: YellowOnButton
        function YellowOnButtonPushed(app, event)
            app.sendMessage("yellow", 1);
        end

        % Button pushed function: YellowOffButton
        function YellowOffButtonPushed(app, event)
            app.sendMessage("yellow", 0);
        end

        % Button pushed function: GreenOnButton
        function GreenOnButtonPushed(app, event)
            app.sendMessage("green", true);
        end

        % Button pushed function: GreenOffButton
        function GreenOffButtonPushed(app, event)
            app.sendMessage("green", false);
        end

        % Button pushed function: SendLEDsButton
        function SendLEDsButtonPushed(app, event)
            payload = [int8(app.RedSwitch.Value == "On")
                        int8(app.YellowSwitch.Value == "On")
                        int8(app.GreenSwitch.Value == "On")];
            app.sendMessage("leds", payload);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MQTTGPIOGUIUIFigure = uifigure('Visible', 'off');
            app.MQTTGPIOGUIUIFigure.Name = 'MQTT GPIO GUI';
            app.RedOnButton = uibutton(app.MQTTGPIOGUIUIFigure, 'push');
            app.RedOnButton.ButtonPushedFcn = createCallbackFcn(app, @RedOnButtonPushed, true);
            app.RedOnButton.Text = 'Red On';
            app.YellowOnButton = uibutton(app.MQTTGPIOGUIUIFigure, 'push');
            app.YellowOnButton.ButtonPushedFcn = createCallbackFcn(app, @YellowOnButtonPushed, true);
            app.YellowOnButton.Text = 'Yellow On';
            app.GreenOnButton = uibutton(app.MQTTGPIOGUIUIFigure, 'push');
            app.GreenOnButton.ButtonPushedFcn = createCallbackFcn(app, @GreenOnButtonPushed, true);
            app.GreenOnButton.Text = 'Green On';
            app.RedOffButton = uibutton(app.MQTTGPIOGUIUIFigure, 'push');
            app.RedOffButton.ButtonPushedFcn = createCallbackFcn(app, @RedOffButtonPushed, true);
            app.RedOffButton.Text = 'Red Off';
            app.YellowOffButton = uibutton(app.MQTTGPIOGUIUIFigure, 'push');
            app.YellowOffButton.ButtonPushedFcn = createCallbackFcn(app, @YellowOffButtonPushed, true);
            app.YellowOffButton.Text = 'Yellow Off';
            app.GreenOffButton = uibutton(app.MQTTGPIOGUIUIFigure, 'push');
            app.GreenOffButton.ButtonPushedFcn = createCallbackFcn(app, @GreenOffButtonPushed, true);
            app.GreenOffButton.Text = 'Green Off';
            app.CounterLabel = uilabel(app.MQTTGPIOGUIUIFigure);
            app.CounterLabel.Text = 'Counter = 0';
            app.RedSwitchLabel = uilabel(app.MQTTGPIOGUIUIFigure);
            app.RedSwitchLabel.Text = 'Red';
            app.RedSwitch = uiswitch(app.MQTTGPIOGUIUIFigure, 'toggle');
            app.YellowSwitchLabel = uilabel(app.MQTTGPIOGUIUIFigure);
            app.YellowSwitchLabel.Text = 'Yellow';
            app.YellowSwitch = uiswitch(app.MQTTGPIOGUIUIFigure, 'toggle');
            app.GreenSwitchLabel = uilabel(app.MQTTGPIOGUIUIFigure);
            app.GreenSwitchLabel.Text = 'Green';
            app.GreenSwitch = uiswitch(app.MQTTGPIOGUIUIFigure, 'toggle');
            app.SendLEDsButton = uibutton(app.MQTTGPIOGUIUIFigure, 'push');
            app.SendLEDsButton.ButtonPushedFcn = createCallbackFcn(app, @SendLEDsButtonPushed, true);
            app.SendLEDsButton.Text = 'Send LEDs';
            % Show the figure after all components are created
            app.MQTTGPIOGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gpio_mqtt_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MQTTGPIOGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MQTTGPIOGUIUIFigure)
        end
    end
end