classdef matlab_chat < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MostRecentMessagerecievedLabel  matlab.ui.control.Label
        SendButton                      matlab.ui.control.Button
        MessagetoSendEditField          matlab.ui.control.EditField
        MessagetoSendEditFieldLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        mqClient

    end
    
    methods (Access = private)
        
        function sendMessage(app)
%             message = app.MessagetoSendEditField.Value;
%             write(app.mqClient, "fisherds", message);  % TODO: Use JSON

            messageObj.type = "chat";
            messageObj.payload = app.MessagetoSendEditField.Value;
            message = jsonencode(messageObj);
            write(app.mqClient, "fisherds", message);

            app.MessagetoSendEditField.Value = "";
        end

                
        function myCallback(app, topic, message)
%             topic
%             message
%             app.MostRecentMessagerecievedLabel.Text = message;  % TODO: Use JSON
            messageCell = jsondecode(message);
            messageType = messageCell.type;
            payload = messageCell.payload
            app.MostRecentMessagerecievedLabel.Text = payload;
            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://broker.hivemq.com");
            app.mqClient.subscribe("fisherds", "Callback", @app.myCallback);

        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            app.sendMessage()
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MessagetoSendEditFieldLabel = uilabel(app.UIFigure);
            app.MessagetoSendEditFieldLabel.Text = 'Message to Send';
            app.MessagetoSendEditField = uieditfield(app.UIFigure, 'text');
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.MostRecentMessagerecievedLabel = uilabel(app.UIFigure);
            app.MostRecentMessagerecievedLabel.Text = 'Most Recent Message recieved';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matlab_chat

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end