classdef RaspTankGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ModesPanel                     matlab.ui.container.Panel
        DriveUntilLineButton           matlab.ui.control.Button
        DriveUntilWallButton           matlab.ui.control.Button
        SpeedSlider                    matlab.ui.control.Slider
        SpeedSliderLabel               matlab.ui.control.Label
        DrivingAreaLabel               matlab.ui.control.Label
        LineSensorReadingsPanel        matlab.ui.container.Panel
        TimestampLabel                 matlab.ui.control.Label
        RightLamp                      matlab.ui.control.Lamp
        RightLampLabel                 matlab.ui.control.Label
        MiddleLamp                     matlab.ui.control.Lamp
        MiddleLampLabel                matlab.ui.control.Label
        LeftLamp                       matlab.ui.control.Lamp
        LeftLampLabel                  matlab.ui.control.Label
        StreamingLineSensorsSwitch     matlab.ui.control.Switch
        StreamingLineSensorsSwitchLabel  matlab.ui.control.Label
        SavedValuesPanel               matlab.ui.container.Panel
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        ArmJointsasNumericInputsPanel  matlab.ui.container.Panel
        GoButton                       matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        Joint3EditField                matlab.ui.control.NumericEditField
        Joint3EditFieldLabel           matlab.ui.control.Label
        Joint2EditField                matlab.ui.control.NumericEditField
        Joint2EditFieldLabel           matlab.ui.control.Label
        Joint1EditField                matlab.ui.control.NumericEditField
        Joint1EditFieldLabel           matlab.ui.control.Label
        ArmJointsasSlidersPanel        matlab.ui.container.Panel
        Joint3Slider                   matlab.ui.control.Slider
        Joint3SliderLabel              matlab.ui.control.Label
        Joint2Slider                   matlab.ui.control.Slider
        Joint2SliderLabel              matlab.ui.control.Label
        Joint1Slider                   matlab.ui.control.Slider
        Joint1SliderLabel              matlab.ui.control.Label
        axes_arm                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        jointAngles = [0 0 0];
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices

        savedPosition1 = [0 0 0];
        savedPosition2 = [0 0 0];
        savedPosition3 = [0 0 0];

        mqClient;
        is_key_held = false;
    end
    
    methods (Access = private)

        function updateSliders(app)
            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);
            app.updateArm();
        end

        function updateArm(app)
            
            % Create the A homogeneous transformation matrices for the given jointAngles.
            [A1, A2, A3] = create_AdeeptArm_A_matrices(app.jointAngles);
            
            
            % Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = A1 * A2;
            T0_3 = A1 * A2 * A3;
            
            % Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;
            
            % Update the patches with the newly transformed vertices
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');

        end


        function sendMessage(app, messageType, payload)
            messageObj.type = messageType;
            messageObj.payload = payload;
            message = jsonencode(messageObj);
            fprintf("Sending: %s\n", message);
            write(app.mqClient, "fisherds", message);
        end

                
        function myCallback(app, topic, message)
            fprintf("Received: %s\n", message);
            messageCell = jsondecode(message);
            messageType = messageCell.type;

            if messageType == "line_sensor_reading"
                payload = messageCell.payload;
                app.LeftLamp.Color = payload.left;
                app.MiddleLamp.Color = payload.middle;
                app.RightLamp.Color = payload.right;
                app.TimestampLabel.Text = payload.timestamp;
            end     
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        
            % Begin: Code that can go into your GUI's StartupFcn.
            clc
            
            % Prepare the axes_arm properties
            app.axes_arm.View = [90 -50];
            app.axes_arm.Visible = 'off';
            
            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            app.axes_arm.XLim = [-160 250];
            app.axes_arm.YLim = [-250 250];
            app.axes_arm.ZLim = [-22 22];
            
            % Create vertices for all the patches
            makeStlLink("AdeeptL0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("AdeeptL1.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));
            
            app.link2Patch = makeStlLink("AdeeptL2.stl", app.axes_arm, [198/255 172/255 20/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));
            
            app.link3Patch = makeStlLink("AdeeptL3.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));
            % End: Code that can go into your GUI's opening function.
            
            
            app.updateArm()
                        
            app.mqClient = mqttclient("tcp://broker.hivemq.com");
            app.mqClient.subscribe("fisherds", "Callback", @app.myCallback);

        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(1) = changingValue;
            app.Joint1EditField.Value = app.jointAngles(1);
            app.updateArm()
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(2) = changingValue;
            app.Joint2EditField.Value = app.jointAngles(2);
            app.updateArm()
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(3) = changingValue;
            app.Joint3EditField.Value = app.jointAngles(3);
            app.updateArm()
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.jointAngles = [0 0 0];
            app.Joint1EditField.Value = 0;
            app.Joint2EditField.Value = 0;
            app.Joint3EditField.Value = 0;
            app.updateSliders(); % which will then call update arm for us.
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.jointAngles(1) = app.Joint1EditField.Value;
            app.jointAngles(2) = app.Joint2EditField.Value;
            app.jointAngles(3) = app.Joint3EditField.Value;
            app.updateSliders(); % which will then call update arm for us.
        end

        % Value changed function: StreamingLineSensorsSwitch
        function StreamingLineSensorsSwitchValueChanged(app, event)
            value = app.StreamingLineSensorsSwitch.Value;
            app.sendMessage("is_streaming_line_sensors", value == "On");
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;

            if app.is_key_held == false
                app.is_key_held = true;
%                 fprintf("Send a go message on a new press\n")

                speedValue = round(app.SpeedSlider.Value);
                if key == "uparrow"
%                     fprintf("Send a forwards message\n")
                    app.sendMessage("drive", [speedValue speedValue])
                end
                if key == "downarrow"
%                     fprintf("Send a reverse message\n")
                    app.sendMessage("drive", [-speedValue -speedValue])
                end
                if key == "leftarrow"
%                     fprintf("Send a left message\n")
                    app.sendMessage("drive", [-speedValue speedValue])
                end
                if key == "rightarrow"
%                     fprintf("Send a right message\n")
                    app.sendMessage("drive", [speedValue -speedValue])
                end
            end


        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
%             key = event.Key

            % CONSIDER: ONly a stop on an arrow key release
            app.is_key_held = false;
%             fprintf("Send a stop message on any release\n")
            app.sendMessage("drive", [0 0])
        end

        % Button pushed function: DriveUntilWallButton
        function DriveUntilWallButtonPushed(app, event)
            app.sendMessage("mode", "drive_until_wall");
        end

        % Button pushed function: DriveUntilLineButton
        function DriveUntilLineButtonPushed(app, event)
            app.sendMessage("mode", "drive_until_line");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.axes_arm = uiaxes(app.UIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.ArmJointsasSlidersPanel = uipanel(app.UIFigure);
            app.ArmJointsasSlidersPanel.Title = 'Arm Joints as Sliders';
            app.Joint1SliderLabel = uilabel(app.ArmJointsasSlidersPanel);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.ArmJointsasSlidersPanel);
            app.Joint1Slider.Limits = [-90 90];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.ArmJointsasSlidersPanel);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.ArmJointsasSlidersPanel);
            app.Joint2Slider.Limits = [-90 90];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.ArmJointsasSlidersPanel);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.ArmJointsasSlidersPanel);
            app.Joint3Slider.Limits = [-90 90];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.ArmJointsasNumericInputsPanel = uipanel(app.UIFigure);
            app.ArmJointsasNumericInputsPanel.Title = 'Arm Joints as Numeric Inputs';
            app.Joint1EditFieldLabel = uilabel(app.ArmJointsasNumericInputsPanel);
            app.Joint1EditFieldLabel.Text = 'Joint 1';
            app.Joint1EditField = uieditfield(app.ArmJointsasNumericInputsPanel, 'numeric');
            app.Joint2EditFieldLabel = uilabel(app.ArmJointsasNumericInputsPanel);
            app.Joint2EditFieldLabel.Text = 'Joint 2';
            app.Joint2EditField = uieditfield(app.ArmJointsasNumericInputsPanel, 'numeric');
            app.Joint3EditFieldLabel = uilabel(app.ArmJointsasNumericInputsPanel);
            app.Joint3EditFieldLabel.Text = 'Joint 3';
            app.Joint3EditField = uieditfield(app.ArmJointsasNumericInputsPanel, 'numeric');
            app.ResetButton = uibutton(app.ArmJointsasNumericInputsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GoButton = uibutton(app.ArmJointsasNumericInputsPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.SavedValuesPanel = uipanel(app.UIFigure);
            app.SavedValuesPanel.Title = 'Saved Values';
            app.Label = uilabel(app.SavedValuesPanel);
            app.Label.Text = '0 0 0 0.75';
            app.Label_2 = uilabel(app.SavedValuesPanel);
            app.Label_2.Text = '1.';
            app.LineSensorReadingsPanel = uipanel(app.UIFigure);
            app.LineSensorReadingsPanel.Title = 'Line Sensor Readings';
            app.StreamingLineSensorsSwitchLabel = uilabel(app.LineSensorReadingsPanel);
            app.StreamingLineSensorsSwitchLabel.Text = 'Streaming Line Sensors';
            app.StreamingLineSensorsSwitch = uiswitch(app.LineSensorReadingsPanel, 'slider');
            app.StreamingLineSensorsSwitch.ValueChangedFcn = createCallbackFcn(app, @StreamingLineSensorsSwitchValueChanged, true);
            app.LeftLampLabel = uilabel(app.LineSensorReadingsPanel);
            app.LeftLampLabel.Text = 'Left';
            app.LeftLamp = uilamp(app.LineSensorReadingsPanel);
            app.MiddleLampLabel = uilabel(app.LineSensorReadingsPanel);
            app.MiddleLampLabel.Text = 'Middle';
            app.MiddleLamp = uilamp(app.LineSensorReadingsPanel);
            app.RightLampLabel = uilabel(app.LineSensorReadingsPanel);
            app.RightLampLabel.Text = 'Right';
            app.RightLamp = uilamp(app.LineSensorReadingsPanel);
            app.TimestampLabel = uilabel(app.LineSensorReadingsPanel);
            app.TimestampLabel.Text = 'Timestamp';
            app.DrivingAreaLabel = uilabel(app.UIFigure);
            app.DrivingAreaLabel.Text = 'Driving Area';
            app.SpeedSliderLabel = uilabel(app.UIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.UIFigure);
            app.SpeedSlider.Value = 100;
            app.ModesPanel = uipanel(app.UIFigure);
            app.ModesPanel.Title = 'Modes';
            app.DriveUntilWallButton = uibutton(app.ModesPanel, 'push');
            app.DriveUntilWallButton.ButtonPushedFcn = createCallbackFcn(app, @DriveUntilWallButtonPushed, true);
            app.DriveUntilWallButton.Text = 'Drive Until Wall';
            app.DriveUntilLineButton = uibutton(app.ModesPanel, 'push');
            app.DriveUntilLineButton.ButtonPushedFcn = createCallbackFcn(app, @DriveUntilLineButtonPushed, true);
            app.DriveUntilLineButton.Text = 'Drive Until Line';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RaspTankGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end