classdef mqChat < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MostRecentMessageLabel       matlab.ui.control.Label
        SendButton                   matlab.ui.control.Button
        MessageToSendEditField       matlab.ui.control.EditField
        MessageToSendEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mqClient
    end
    
    methods (Access = private)
        
        function sendMessage(app)
            messageStruct.type = "chat";
            messageStruct.payload = app.MessageToSendEditField.Value;
            message = jsonencode(messageStruct);
            app.mqClient.write("fisherds", message);

            app.MessageToSendEditField.Value = "";
        end

        function myCallback(app, topic, message)
            messageStruct = jsondecode(message);
            messageType = messageStruct.type;

            if messageType == "chat"
                payload = messageStruct.payload;   % Dangerous line!
                app.MostRecentMessageLabel.Text = payload;
            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://broker.hivemq.com");
            app.mqClient.subscribe("fisherds", "Callback", @app.myCallback)

        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            app.sendMessage()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MessageToSendEditFieldLabel = uilabel(app.UIFigure);
            app.MessageToSendEditFieldLabel.Text = 'Message To Send';
            app.MessageToSendEditField = uieditfield(app.UIFigure, 'text');
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.MostRecentMessageLabel = uilabel(app.UIFigure);
            app.MostRecentMessageLabel.Text = 'Most Recent Message';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mqChat

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end