classdef RaspTankGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ModesPanel                      matlab.ui.container.Panel
        DriveuntilWallButton            matlab.ui.control.Button
        SpeedSlider                     matlab.ui.control.Slider
        SpeedSliderLabel                matlab.ui.control.Label
        ClickthefigurewindowtosetthekeyboardfocusonthefigureLabel  matlab.ui.control.Label
        LineSensorsPanel                matlab.ui.container.Panel
        TimestampLabel                  matlab.ui.control.Label
        RightLamp                       matlab.ui.control.Lamp
        RightLampLabel                  matlab.ui.control.Label
        MiddleLamp                      matlab.ui.control.Lamp
        MiddleLampLabel                 matlab.ui.control.Label
        LeftLamp                        matlab.ui.control.Lamp
        LeftLampLabel                   matlab.ui.control.Label
        LineSensorStreamingSwitch       matlab.ui.control.Switch
        LineSensorStreamingSwitchLabel  matlab.ui.control.Label
        ArmJointAnglesviaSliderPanel    matlab.ui.container.Panel
        Joint3Slider                    matlab.ui.control.Slider
        Joint3SliderLabel               matlab.ui.control.Label
        Joint2Slider                    matlab.ui.control.Slider
        Joint2SliderLabel               matlab.ui.control.Label
        Joint1Slider                    matlab.ui.control.Slider
        Joint1SliderLabel               matlab.ui.control.Label
        axes_arm                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        jointAngles = [0 0 0];
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices

        mqClient;
        isKeyHeldDown = false;
    end
    
    methods (Access = private)
        
        function updateArm(app)
                        
            % Create the A homogeneous transformation matrices for the given jointAngles.
            [A1, A2, A3] = create_AdeeptArm_A_matrices(app.jointAngles);
            
            % Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = A1 * A2;
            T0_3 = A1 * A2 * A3;
            
            % Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;
            
            % Update the patches with the newly transformed vertices
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');

        end

        function sendMessage(app, messageType, payload)
            messageStruct.type = messageType;
            messageStruct.payload = payload;
            message = jsonencode(messageStruct);
            fprintf("Sending: %s\n", message);
            app.mqClient.write("fisherds", message);
        end

        function myCallback(app, topic, message)
            fprintf("Received: %s\n", message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type;

            if messageType == "line_sensor_reading"
                payload = messageStruct.payload;

                app.LeftLamp.Color = payload.left;
                app.MiddleLamp.Color = payload.middle;
                app.RightLamp.Color = payload.right;

                app.TimestampLabel.Text = payload.timestamp;
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        
            % Begin: Code that can go into your GUI's StartupFcn.
            clc
            
            % Prepare the axes_arm properties
            app.axes_arm.View = [90 -50];
            app.axes_arm.Visible = 'off';
            
            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            app.axes_arm.XLim = [-160 250];
            app.axes_arm.YLim = [-250 250];
            app.axes_arm.ZLim = [-22 22];
            
            % Create vertices for all the patches
            makeStlLink("AdeeptL0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("AdeeptL1.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));
            
            app.link2Patch = makeStlLink("AdeeptL2.stl", app.axes_arm, [198/255 172/255 20/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));
            
            app.link3Patch = makeStlLink("AdeeptL3.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));
            % End: Code that can go into your GUI's opening function.
            
            app.updateArm()

            app.mqClient = mqttclient("tcp://broker.hivemq.com");
            app.mqClient.subscribe("fisherds", "Callback", @app.myCallback)

        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(1) = changingValue;
            app.updateArm()
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(2) = changingValue;
            app.updateArm()
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(3) = changingValue;
            app.updateArm()
        end

        % Value changed function: LineSensorStreamingSwitch
        function LineSensorStreamingSwitchValueChanged(app, event)
            value = app.LineSensorStreamingSwitch.Value;
            
            app.sendMessage("is_streaming", value == "On");

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
%             fprintf("Pressed ...\n")
            key = event.Key;
            
            if ~app.isKeyHeldDown
%                 fprintf("This is a new press!\n")
                speed = round(app.SpeedSlider.Value);
                if key == "uparrow"
%                     fprintf("Up\n");
                    app.sendMessage("drive", [speed speed]);
                end
                if key == "downarrow"
%                     fprintf("Down\n");
                    app.sendMessage("drive", [-speed -speed]);
                end
                if key == "leftarrow"
%                     fprintf("Left\n");
                    app.sendMessage("drive", [-speed speed]);
                end
                if key == "rightarrow"
%                     fprintf("Right\n");
                    app.sendMessage("drive", [speed -speed]);
                end
            end

            app.isKeyHeldDown = true;
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
%             fprintf("Released....\n")
            key = event.Key;

            app.isKeyHeldDown = false;
            app.sendMessage("drive", [0 0]);
            
        end

        % Button pushed function: DriveuntilWallButton
        function DriveuntilWallButtonPushed(app, event)
            app.sendMessage("mode", "drive_until_wall");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.axes_arm = uiaxes(app.UIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.ArmJointAnglesviaSliderPanel = uipanel(app.UIFigure);
            app.ArmJointAnglesviaSliderPanel.Title = 'Arm Joint Angles via Slider';
            app.Joint1SliderLabel = uilabel(app.ArmJointAnglesviaSliderPanel);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.ArmJointAnglesviaSliderPanel);
            app.Joint1Slider.Limits = [-90 90];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.ArmJointAnglesviaSliderPanel);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.ArmJointAnglesviaSliderPanel);
            app.Joint2Slider.Limits = [-90 90];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.ArmJointAnglesviaSliderPanel);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.ArmJointAnglesviaSliderPanel);
            app.Joint3Slider.Limits = [-90 90];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.LineSensorsPanel = uipanel(app.UIFigure);
            app.LineSensorsPanel.Title = 'Line Sensors';
            app.LineSensorStreamingSwitchLabel = uilabel(app.LineSensorsPanel);
            app.LineSensorStreamingSwitchLabel.Text = 'Line Sensor Streaming';
            app.LineSensorStreamingSwitch = uiswitch(app.LineSensorsPanel, 'slider');
            app.LineSensorStreamingSwitch.ValueChangedFcn = createCallbackFcn(app, @LineSensorStreamingSwitchValueChanged, true);
            app.LeftLampLabel = uilabel(app.LineSensorsPanel);
            app.LeftLampLabel.Text = 'Left';
            app.LeftLamp = uilamp(app.LineSensorsPanel);
            app.MiddleLampLabel = uilabel(app.LineSensorsPanel);
            app.MiddleLampLabel.Text = 'Middle';
            app.MiddleLamp = uilamp(app.LineSensorsPanel);
            app.RightLampLabel = uilabel(app.LineSensorsPanel);
            app.RightLampLabel.Text = 'Right';
            app.RightLamp = uilamp(app.LineSensorsPanel);
            app.TimestampLabel = uilabel(app.LineSensorsPanel);
            app.TimestampLabel.Text = 'Timestamp';
            app.ClickthefigurewindowtosetthekeyboardfocusonthefigureLabel = uilabel(app.UIFigure);
            app.ClickthefigurewindowtosetthekeyboardfocusonthefigureLabel.Text = 'Click the figure window to set the keyboard focus on the figure.';
            app.SpeedSliderLabel = uilabel(app.UIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.UIFigure);
            app.SpeedSlider.Value = 90;
            app.ModesPanel = uipanel(app.UIFigure);
            app.ModesPanel.Title = 'Modes';
            app.DriveuntilWallButton = uibutton(app.ModesPanel, 'push');
            app.DriveuntilWallButton.ButtonPushedFcn = createCallbackFcn(app, @DriveuntilWallButtonPushed, true);
            app.DriveuntilWallButton.Text = 'Drive until Wall';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RaspTankGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end